classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TableButton             matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        Image_2                 matlab.ui.control.Image
        TestLabel               matlab.ui.control.Label
        SubjectLabel            matlab.ui.control.Label
        TestImageDropDownLabel  matlab.ui.control.Label
        TestImageDropDown       matlab.ui.control.DropDown
        StartButton             matlab.ui.control.Button
        FaceRecognitionLabel    matlab.ui.control.Label
        Label                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        Newnet % Description
        table % Description
        scores
    end
    
    methods (Access = public)
        
        function setNewnet(app, newnet, accuracy)
            app.Newnet = newnet;
            app.Label.Text = "Accuracy Percentage : " + accuracy + "%";
        end
        
        function test(app, image_url, newnet)
            % Test a new Image
            % use code below with giving path to your new image
             img = imread(image_url);
             [img,face] = cropface(img);
             
             % face value is 1 when it detects face in image or 0
             if face == 1
               img = imresize(img,[227 227]);
               predict = classify(newnet,img);
             end
             
             nameofs01 = 'Dylan';
             nameofs02 = 'Fatin';
             nameofs03 = 'Yvonne';
             nameofs04 = 'Syafiq';
             nameofs05 = 'Fang';
             
             if predict=='s01'
               app.Image_2.ImageSource = "photos\s01\1.jpg";
               app.SubjectLabel.Text = nameofs01;
             elseif  predict=='s02'   
               app.Image_2.ImageSource = "photos\s02\1.jpg";
               app.SubjectLabel.Text = nameofs02;
             elseif  predict=='s03'
               app.Image_2.ImageSource = "photos\s03\1.jpg";
               app.SubjectLabel.Text = nameofs03;
             elseif  predict=='s04'
               app.Image_2.ImageSource = "photos\s04\1.jpg";
               app.SubjectLabel.Text = nameofs04;
             elseif predict=='s05'
               app.Image_2.ImageSource = "photos\s05\1.jpg";
               app.SubjectLabel.Text = nameofs05;
             end
             
             [predict,score] = classify(newnet,img)
             fprintf('predict %s\n',predict);
             fprintf('score is %d\n',score);
             app.scores = score;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ImageSource = "test_photo\img_1.JPG";
            app.test(app.TestImageDropDown.Value, app.Newnet);
        end

        % Button pushed function: TableButton
        function TableButtonPushed(app, event)
            app.table = table1;
            
            index = 1;
            name = 'noname';
            
             for score = app.scores
                 if index == 1
                     name = 'Dylan'
                 end
                 if index == 2
                     name = 'Fatin'
                 end
                 if index == 3
                     name = 'Yvonne'
                 end
                 if index == 4
                     name = 'Syafiq'
                 end
                 if index == 5
                     name = 'Fang'
                 end
                 
                 index1 = num2str(index);
                 score1 = num2str(score);
                 score1 = strrep(score1, 'e', ' x10^');
                 
                 app.table.addRow(index1, name, score1);
                 index = index + 1;
             end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.test(app.TestImageDropDown.Value, app.Newnet);
        end

        % Value changed function: TestImageDropDown
        function TestImageDropDownValueChanged(app, event)
            value = app.TestImageDropDown.Value;
            app.Image.ImageSource = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TableButton = uibutton(app.UIFigure, 'push');
            app.TableButton.ButtonPushedFcn = createCallbackFcn(app, @TableButtonPushed, true);
            app.TableButton.Text = 'Table';
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.TestLabel = uilabel(app.UIFigure);
            app.TestLabel.Text = 'Test';
            app.SubjectLabel = uilabel(app.UIFigure);
            app.SubjectLabel.Text = 'Subject';
            app.TestImageDropDownLabel = uilabel(app.UIFigure);
            app.TestImageDropDownLabel.Text = 'Test Image';
            app.TestImageDropDown = uidropdown(app.UIFigure);
            app.TestImageDropDown.Items = {'img_1.JPG', 'img_2.JPG', 'img_3.JPG', 'img_4.JPG', 'img_5.JPG', 'img_6.JPG', 'img_7.JPG'};
            app.TestImageDropDown.ItemsData = {'test_photo\img_1.JPG', 'test_photo\img_2.JPG', 'test_photo\img_3.JPG', 'test_photo\img_4.JPG', 'test_photo\img_5.JPG', 'test_photo\img_6.JPG', 'test_photo\img_7.JPG'};
            app.TestImageDropDown.ValueChangedFcn = createCallbackFcn(app, @TestImageDropDownValueChanged, true);
            app.TestImageDropDown.Value = 'test_photo\img_1.JPG';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FaceRecognitionLabel = uilabel(app.UIFigure);
            app.FaceRecognitionLabel.Text = 'Face Recognition';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end